classdef segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        EdgeDetectionTab              matlab.ui.container.Tab
        PixelEditField                matlab.ui.control.NumericEditField
        PixelEditFieldLabel           matlab.ui.control.Label
        RadiusEditField               matlab.ui.control.NumericEditField
        RadiusEditFieldLabel          matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        DetectionMethodDropDownLabel  matlab.ui.control.Label
        EdgeExecuteButton             matlab.ui.control.Button
        EdgeUploadImageButton         matlab.ui.control.Button
        SegmentationImageLabel        matlab.ui.control.Label
        EdgeDetectionImageLabel       matlab.ui.control.Label
        InputImageLabel_2             matlab.ui.control.Label
        SegmentedImage                matlab.ui.control.Image
        EdgeImage                     matlab.ui.control.Image
        EdgeInputImage                matlab.ui.control.Image
        HoughTransformationTab        matlab.ui.container.Tab
        ExecuteButton                 matlab.ui.control.Button
        DetectCannyButton             matlab.ui.control.Button
        ThresholdHoughEditField       matlab.ui.control.NumericEditField
        ThresholdEditField_2Label     matlab.ui.control.Label
        HoughParametersLabel          matlab.ui.control.Label
        PeakNumberEditField           matlab.ui.control.NumericEditField
        PeakNumberEditFieldLabel      matlab.ui.control.Label
        EdgeDetectionCannyParametersLabel  matlab.ui.control.Label
        SigmaEditField                matlab.ui.control.NumericEditField
        SigmaEditFieldLabel           matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        DetectionTypeDropDown         matlab.ui.control.DropDown
        DetectionTypeDropDownLabel    matlab.ui.control.Label
        OutputImage                   matlab.ui.control.Image
        CannyImage                    matlab.ui.control.Image
        UploadImageButton             matlab.ui.control.Button
        OutputImageLabel              matlab.ui.control.Label
        EdgeDetectionwithCannyLabel   matlab.ui.control.Label
        InputImageLabel               matlab.ui.control.Label
        InputImage                    matlab.ui.control.Image
        HoughUIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function cannyImage = edge_detection(~, img, cannyThreshold, cannySigma)
            % Convert to grayscale if the image is RGB
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
        
            % Perform edge detection using the Canny method
            cannyImage = edge(img, 'Canny', cannyThreshold, cannySigma);
        end

        function linesImage = detect_lines(~, originalImg, cannyImage, houghAxes, peakNumber, houghThreshold)
            % Ensure the image is binary for the Hough transform
            BW = cannyImage > 0;
        
            % Compute the Hough transform
            [H, theta, rho] = hough(BW);
        
            % Normalize the Hough transform matrix for visualization
            H_normalized = mat2gray(H);
        
            % Display the Hough transform matrix in the specified axes
            imshow(imadjust(H_normalized), [], ...
                'XData', theta, ...
                'YData', rho, ...
                'Parent', houghAxes, ...
                'InitialMagnification', 'fit');
            xlabel(houghAxes, '\theta (degrees)');
            ylabel(houghAxes, '\rho');
            axis(houghAxes, 'on');
            axis(houghAxes, 'normal');
            colormap(houghAxes, hot);
        
            % Find Hough peaks (lines with highest votes)
            P = houghpeaks(H, peakNumber, 'threshold', ceil(houghThreshold * max(H(:))));
        
            % Identify lines based on the peaks
            lines = houghlines(BW, theta, rho, P, 'FillGap', 5, 'MinLength', 7);
        
            % Create an offscreen axes for plotting
            fig = figure('Visible', 'off'); % Create a figure without displaying it
            ax = axes('Parent', fig); % Create offscreen axes
            imshow(originalImg, 'Parent', ax); % Display original image
            hold(ax, 'on');
        
            max_len = 0;
        
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
        
                % Plot the lines on the image
                plot(ax, xy(:,1), xy(:,2), 'LineWidth', 2, 'Color', 'green');
                plot(ax, xy(1,1), xy(1,2), 'x', 'LineWidth', 2, 'Color', 'yellow');
                plot(ax, xy(2,1), xy(2,2), 'x', 'LineWidth', 2, 'Color', 'red');
        
                % Determine the longest line segment
                len = norm(lines(k).point1 - lines(k).point2);
                if len > max_len
                    max_len = len;
                    xy_long = xy; % Store the longest line coordinates
                end
            end
        
            % Highlight the longest line segment
            plot(ax, xy_long(:,1), xy_long(:,2), 'LineWidth', 2, 'Color', 'red');
        
            % Capture the content of the offscreen axes
            frame = getframe(ax); % Capture the axes content
            linesImage = frame.cdata; % Extract the image data
        
            % Close the offscreen figure
            close(fig);
        end
    
        function circlesImage = detect_circles(~, originalImg, cannyImage, houghAxes)
            % Ensure the image is binary for the Hough transform
            BW = cannyImage > 0;
        
            % Initialize parameters
            [rows, cols] = size(BW); % Dimensions of the image
            rMin = 15; % Minimum radius
            rMax = 100; % Maximum radius
            threshold = 0.5; % Threshold for detection
        
            % Preallocate the accumulator space for (a, b, r)
            accSize = [rows, cols, rMax - rMin + 1];
            accumulator = zeros(accSize);
        
            % Compute gradient and direction using edge detected image
            [Gmag, Gdir] = imgradient(BW);
        
            % Iterate over edge pixels
            [yEdge, xEdge] = find(BW);
            for i = 1:length(xEdge)
                x = xEdge(i);
                y = yEdge(i);
        
                % Skip pixels with no strong gradient
                if Gmag(y, x) == 0
                    continue;
                end
        
                % Accumulate votes in the parameter space
                for r = rMin:rMax
                    theta = 0:pi/100:2*pi; % Discretize the angle
                    a = round(x - r * cos(theta)); % x-coordinate of the circle center
                    b = round(y - r * sin(theta)); % y-coordinate of the circle center
        
                    % Filter valid (a, b) within image boundaries
                    validIdx = (a > 0 & a <= cols & b > 0 & b <= rows);
                    a = a(validIdx);
                    b = b(validIdx);
        
                    % Accumulate votes in the parameter space
                    for k = 1:length(a)
                        accumulator(b(k), a(k), r - rMin + 1) = accumulator(b(k), a(k), r - rMin + 1) + 1;
                    end
                end
            end
        
            % Find peaks in the accumulator space
            [aIdx, bIdx, rIdx] = ind2sub(size(accumulator), find(accumulator > threshold * max(accumulator(:))));
        
            % Convert indices to actual circle parameters
            centers = [bIdx, aIdx];
            radii = rIdx + rMin - 1;
        
            % Create an offscreen axes for plotting
            fig = figure('Visible', 'off'); % Create a figure without displaying it
            ax = axes('Parent', fig); % Create offscreen axes
            imshow(originalImg, 'Parent', ax); % Display original image
            hold(ax, 'on');
        
            % Plot detected circles on the image
            for k = 1:length(radii)
                viscircles(ax, centers(k, :), radii(k), 'EdgeColor', 'b');
            end
        
            % Capture the content of the offscreen axes
            frame = getframe(ax); % Capture the axes content
            circlesImage = frame.cdata; % Extract the image data
        
            % Close the offscreen figure
            close(fig);
        
            % Optionally display circles in the Hough transform axes (if needed)
            if ~isempty(houghAxes)
                cla(houghAxes); % Clear previous content
                imshow(mat2gray(max(accumulator, [], 3)), 'Parent', houghAxes); % Display 2D projection
                title(houghAxes, 'Hough Transform for Circles');
            end
        end
    
        function segmentedData = getSegmentation(~, imageData, edgeImageData, radius, minimumPixel)
            
            % Create structuring element
            se = strel('disk', radius);
            
            % Close the edge image
            closedImageData = imclose(edgeImageData, se);
            
            % Remove border objects
            borderImageData = imclearborder(closedImageData);
            
            % Fill holes in edge image
            filledEdgeImageData = imfill(borderImageData, 'holes');
            
            % Open the edge image
            openedImageData = imopen(filledEdgeImageData, se);
            
            % Remove small objects
            removedImageData = bwareaopen(openedImageData, minimumPixel);
            
            % Apply mask to original image
            segmentedData = imageData .* uint8(removedImageData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;
            end
        
            % Read the image from the selected file
            imgPath = fullfile(path, file);
            app.InputImage.ImageSource = imgPath;
        end

        % Button pushed function: DetectCannyButton
        function DetectCannyButtonPushed(app, event)
            img = imread(app.InputImage.ImageSource);
        
            % Edge detection
            cannyThreshold = app.ThresholdEditField.Value;
            cannySigma = app.SigmaEditField.Value;
            edgeImg = app.edge_detection(img, cannyThreshold, cannySigma);

            % Generate unique temporary file names
            timestamp = datestr(now, 'yyyymmddHHMMSSFFF'); % Unique timestamp
            edgeTempPath = fullfile(tempdir, ['canny_image_', timestamp, '.png']);

            % Save the processed images to the unique temporary files
            imwrite(edgeImg, edgeTempPath);

            % Update the app's image components
            app.CannyImage.ImageSource = edgeTempPath;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            img = imread(app.InputImage.ImageSource);
            edgeImg = imread(app.CannyImage.ImageSource);
            
            % Check dropdown value and detect either lines or circles
            dropdownValue = app.DetectionTypeDropDown.Value; % Assumes dropdown has 'Value' property
            if strcmp(dropdownValue, 'Lines')
                houghPeakNumber = app.PeakNumberEditField.Value;
                houghThreshold = app.ThresholdHoughEditField.Value;
                detectedImg = app.detect_lines(img, edgeImg, app.HoughUIAxes, houghPeakNumber, houghThreshold);
            elseif strcmp(dropdownValue, 'Circles')
                detectedImg = app.detect_circles(img, edgeImg, app.HoughUIAxes);
            else
                uialert(app.UIFigure, 'Invalid selection. Please choose Lines or Circles.', 'Error');
                return;
            end

            % Generate unique temporary file names
            timestamp = datestr(now, 'yyyymmddHHMMSSFFF'); % Unique timestamp
            detectedTempPath = fullfile(tempdir, ['detected_image_', timestamp, '.png']);
    
            % Save the processed images to the unique temporary files
            imwrite(detectedImg, detectedTempPath);
    
            % Update the app's image components
            app.OutputImage.ImageSource = detectedTempPath;
        end

        % Button pushed function: EdgeUploadImageButton
        function EdgeUploadImageButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;
            end
        
            % Read the image from the selected file
            imgPath = fullfile(path, file);
            app.EdgeInputImage.ImageSource = imgPath;
        end

        % Button pushed function: EdgeExecuteButton
        function EdgeExecuteButtonPushed(app, event)
            image = imread(app.EdgeInputImage.ImageSource);

            % Check dropdown value
            dropdownValue = app.DetectionMethodDropDown.Value; % Assumes dropdown has 'Value' property
            switch dropdownValue
                case 'Laplace'
                    imageEdge = uint8(laplace(rgb2gray(image)));
                case 'LoG'
                    imageEdge = uint8(lapGaus(rgb2gray(image)));
                case 'Prewitt'
                    imageEdge = uint8(prewitt(rgb2gray(image)));
                case 'Roberts'
                    imageEdge = uint8(roberts(rgb2gray(image)));
                case 'Sobel'
                    imageEdge = uint8(sobel(rgb2gray(image)));
                case 'Canny'
                    imageEdge = app.edge_detection(image, 0.28, 1.5);
            end

            edgeImage = imageEdge;

            % Image Segmentation
            % Define radius and minimum pixel area for segmentation
            radius = app.RadiusEditField.Value; % Adjust as needed
            minimumPixel = app.PixelEditField.Value; % Adjust as needed
            segmentedImage = app.getSegmentation(image, imageEdge, radius, minimumPixel);
        
            % Save processed images to temporary files
            timestamp = datestr(now, 'yyyymmddHHMMSSFFF');
            edgeTempPath = fullfile(tempdir, ['edge_image_', timestamp, '.png']);
            segmentedTempPath = fullfile(tempdir, ['segmented_image_', timestamp, '.png']);
        
            imwrite(edgeImage, edgeTempPath);
            imwrite(segmentedImage, segmentedTempPath);
        
            % Update app image components
            app.EdgeImage.ImageSource = edgeTempPath;
            app.SegmentedImage.ImageSource = segmentedTempPath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EdgeDetectionTab = uitab(app.TabGroup);
            app.EdgeDetectionTab.Title = 'Edge Detection';
            app.EdgeInputImage = uiimage(app.EdgeDetectionTab);
            app.EdgeImage = uiimage(app.EdgeDetectionTab);
            app.SegmentedImage = uiimage(app.EdgeDetectionTab);
            app.InputImageLabel_2 = uilabel(app.EdgeDetectionTab);
            app.InputImageLabel_2.Text = 'Input Image';
            app.EdgeDetectionImageLabel = uilabel(app.EdgeDetectionTab);
            app.EdgeDetectionImageLabel.Text = 'Edge Detection Image';
            app.SegmentationImageLabel = uilabel(app.EdgeDetectionTab);
            app.SegmentationImageLabel.Text = 'Segmentation Image';
            app.EdgeUploadImageButton = uibutton(app.EdgeDetectionTab, 'push');
            app.EdgeUploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeUploadImageButtonPushed, true);
            app.EdgeUploadImageButton.Text = 'Upload Image';
            app.EdgeExecuteButton = uibutton(app.EdgeDetectionTab, 'push');
            app.EdgeExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeExecuteButtonPushed, true);
            app.EdgeExecuteButton.Text = 'Execute';
            app.DetectionMethodDropDownLabel = uilabel(app.EdgeDetectionTab);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.EdgeDetectionTab);
            app.DetectionMethodDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.DetectionMethodDropDown.Value = 'Laplace';
            app.RadiusEditFieldLabel = uilabel(app.EdgeDetectionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.EdgeDetectionTab, 'numeric');
            app.PixelEditFieldLabel = uilabel(app.EdgeDetectionTab);
            app.PixelEditFieldLabel.Text = 'Pixel';
            app.PixelEditField = uieditfield(app.EdgeDetectionTab, 'numeric');
            app.HoughTransformationTab = uitab(app.TabGroup);
            app.HoughTransformationTab.Title = 'Hough Transformation';
            app.HoughUIAxes = uiaxes(app.HoughTransformationTab);
            title(app.HoughUIAxes, 'Hough Transform')
            xlabel(app.HoughUIAxes, 'X')
            ylabel(app.HoughUIAxes, 'Y')
            zlabel(app.HoughUIAxes, 'Z')
            app.InputImage = uiimage(app.HoughTransformationTab);
            app.InputImageLabel = uilabel(app.HoughTransformationTab);
            app.InputImageLabel.Text = 'Input Image';
            app.EdgeDetectionwithCannyLabel = uilabel(app.HoughTransformationTab);
            app.EdgeDetectionwithCannyLabel.Text = 'Edge Detection with Canny';
            app.OutputImageLabel = uilabel(app.HoughTransformationTab);
            app.OutputImageLabel.Text = 'Output Image';
            app.UploadImageButton = uibutton(app.HoughTransformationTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.CannyImage = uiimage(app.HoughTransformationTab);
            app.OutputImage = uiimage(app.HoughTransformationTab);
            app.DetectionTypeDropDownLabel = uilabel(app.HoughTransformationTab);
            app.DetectionTypeDropDownLabel.Text = 'Detection Type';
            app.DetectionTypeDropDown = uidropdown(app.HoughTransformationTab);
            app.DetectionTypeDropDown.Items = {'Lines', 'Circles'};
            app.DetectionTypeDropDown.Value = 'Lines';
            app.ThresholdEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.ThresholdEditField.Value = 0.1;
            app.SigmaEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.SigmaEditField.Value = 1;
            app.EdgeDetectionCannyParametersLabel = uilabel(app.HoughTransformationTab);
            app.EdgeDetectionCannyParametersLabel.Text = 'Edge Detection Canny Parameters';
            app.PeakNumberEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.PeakNumberEditFieldLabel.Text = 'Peak Number';
            app.PeakNumberEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.PeakNumberEditField.Value = 1;
            app.HoughParametersLabel = uilabel(app.HoughTransformationTab);
            app.HoughParametersLabel.Text = 'Hough Parameters';
            app.ThresholdEditField_2Label = uilabel(app.HoughTransformationTab);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdHoughEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.ThresholdHoughEditField.Value = 0.1;
            app.DetectCannyButton = uibutton(app.HoughTransformationTab, 'push');
            app.DetectCannyButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCannyButtonPushed, true);
            app.DetectCannyButton.Text = 'Detect Canny';
            app.ExecuteButton = uibutton(app.HoughTransformationTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end